classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ConfidenceScoreLabel    matlab.ui.control.Label
        StatusLabel             matlab.ui.control.Label
        ResultsLabel            matlab.ui.control.Label
        HowitworksLabel         matlab.ui.control.Label
        TermsLabel              matlab.ui.control.Label
        HelpCenterLabel         matlab.ui.control.Label
        PrivacyLabel            matlab.ui.control.Label
        VisualizationPanel      matlab.ui.container.Panel
        DisplayDropDown         matlab.ui.control.DropDown
        DisplayDropDownLabel    matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        ControlPanel            matlab.ui.container.Panel
        VolumeSlider            matlab.ui.control.Slider
        VolumeSliderLabel       matlab.ui.control.Label
        PlaybackButton          matlab.ui.control.Button
        ShowfeaturesButton      matlab.ui.control.Button
        AnalizeButton           matlab.ui.control.Button
        LoadaudioButton         matlab.ui.control.Button
        WASHTONEDETECTIONLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % These are the Private properties for app logic
    properties (Access = private)
         Model        % Trained SVM model (from our Classification Learner)
        AudioData    % Loaded audio waveform
        SampleRate   % Sampling rate of the audio
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadaudioButton
        function LoadaudioButtonPushed(app, event)
     try
        % Update status 
        app.StatusLabel.Text = "Status: Waiting for file selection...";
        drawnow;  % This is to cause refresh cause the app had issues 

        % Open file dialog to load the files
        [file, path] = uigetfile('*.wav', 'Select an audio file');
        if isequal(file,0) % if  no file give status
            app.StatusLabel.Text = "Status: No file selected";
            drawnow;
            return
        end

        % Update status before reading
        app.StatusLabel.Text = "Status: Loading audio...";
        drawnow;

        % Read the audio
        audioFilePath = fullfile(path, file);
        [app.AudioData, app.SampleRate] = audioread(audioFilePath);

        % Update status after loading
        app.StatusLabel.Text = "Status: Audio loaded successfully";
        drawnow;

        % Optional: display filename
        app.ResultsLabel.Text = "Loaded: " + file; % LABEL
        drawnow;

    catch ME % For a better error mssage
        app.StatusLabel.Text = "Status: Error loading audio";
        drawnow;
        disp("Error message: " + ME.message);
     end
        end

        % Button pushed function: AnalizeButton
        function AnalizeButtonPushed(app, event)
      try
        % Check if audio is loaded
        if isempty(app.AudioData)
            app.StatusLabel.Text = "Status: No audio loaded";
            return
        end

        S = load('faultModelSVM.mat');  % your saved model file
        app.Model = S.trainedModel;     % use the exact variable name from the MAT file

        % Extract features from the loaded audio
        Features = extractAudioFeatures(app.AudioData, app.SampleRate);

        % Predict fault using the SVM model
        fault = app.Model.predictFcn(Features);

        % confidence scores 
        % the convidence score was giving -0.somthing so i had to make
        % adjustments to the math
        [~, scores] = app.Model.predictFcn(Features);
    rawScore = max(scores, [], 2);        
    confidencePercent = 1 / (1 + exp(-rawScore)) * 100;  
    app.ConfidenceScoreLabel.Text = "Confidence Score: " + string(round(confidencePercent,2)) + "%";


        % Update UI labels with results
        app.ResultsLabel.Text = "Results: " + string(fault);
        app.StatusLabel.Text = "Status: Analysis complete";

    catch ME
        % Handle errors 
        app.StatusLabel.Text = "Status: Error during analysis";
         app.ResultsLabel.Text = "Error: " + ME.message;
        disp(ME);
      end
       
        end

        % Button pushed function: ShowfeaturesButton
        function ShowfeaturesButtonPushed(app, event)
     try
        % Check if audio is loaded
        if isempty(app.AudioData) || isempty(app.SampleRate)
            app.StatusLabel.Text = "Status: No audio loaded";
            return
        end

        % Extract features
        Features = extractAudioFeatures(app.AudioData, app.SampleRate);

        % Display features in command window
        disp('Extracted Audio Features:');
        disp(Features);

        % Update status label
        app.StatusLabel.Text = "Status: success (see command window)";
    catch ME
        app.StatusLabel.Text = "Status: Error extracting features";
        disp("Error details: " + ME.message);
    end
        end

        % Button pushed function: PlaybackButton
        function PlaybackButtonPushed(app, event)
     try
        % Check if audio is loaded
        if isempty(app.AudioData) || isempty(app.SampleRate)
            app.StatusLabel.Text = "Status: No audio loaded";
            return
        end

        % Update status
        app.StatusLabel.Text = "Status: Playing audio...";
        drawnow;  % to force UI update

        % Play the audio at the selected volume
        % VolumeSlider
        volume = app.VolumeSlider.Value;  
        sound(app.AudioData * volume, app.SampleRate);

        % Wait for audio to finish playing 
        pause(length(app.AudioData)/app.SampleRate);

        % Update status
        app.StatusLabel.Text = "Status: Playback finished";

     catch ME  % for specific error handling 
        app.StatusLabel.Text = "Status: Error during playback";
        app.ResultsLabel.Text = "Error: " + ME.message;
        disp(ME.message);
    end
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            value = app.DisplayDropDown.Value;

    % To Check if audio is loaded
    if isempty(app.AudioData) || isempty(app.SampleRate)
        app.StatusLabel.Text = "Status: No audio loaded";
        return
    end

    % Convert to mono if stereo
    audio = app.AudioData;
    if size(audio,2) > 1
        audio = mean(audio,2);
    end

    % Time axis
    t = (0:length(audio)-1) / app.SampleRate;

    % Clear previous plot
    cla(app.UIAxes);

    switch value

        case 'Waveform'
            plot(app.UIAxes, t, audio);
            title(app.UIAxes, 'Waveform');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');

        case 'Spectogram'
            spectrogram(audio, 256, [], [], app.SampleRate, 'yaxis', 'Parent', app.UIAxes);
            title(app.UIAxes, 'Spectrogram');

        case 'Frequency Spectrum'
            Y = fft(audio);
            f = (0:length(Y)-1) * app.SampleRate / length(Y);

            plot(app.UIAxes, f, abs(Y));
            xlim(app.UIAxes, [0 app.SampleRate/2]);
            title(app.UIAxes, 'Frequency Spectrum');
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Magnitude');

    end

    % Update status
    app.StatusLabel.Text = "Status: Display updated"; % just an addition

            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
             % Get click position
    clickPoint = app.UIAxes.CurrentPoint;
    x = clickPoint(1,1);
    y = clickPoint(1,2);

    % Show in status label
    app.StatusLabel.Text = "Clicked at: (" + string(x) + ", " + string(y) + ")";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WASHTONEDETECTIONLabel = uilabel(app.UIFigure);
            app.WASHTONEDETECTIONLabel.Text = 'WASHTONE DETECTION';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.LoadaudioButton = uibutton(app.ControlPanel, 'push');
            app.LoadaudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaudioButtonPushed, true);
            app.LoadaudioButton.Text = 'Load audio';
            app.AnalizeButton = uibutton(app.ControlPanel, 'push');
            app.AnalizeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizeButtonPushed, true);
            app.AnalizeButton.Text = 'Analize';
            app.ShowfeaturesButton = uibutton(app.ControlPanel, 'push');
            app.ShowfeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowfeaturesButtonPushed, true);
            app.ShowfeaturesButton.Text = 'Show features';
            app.PlaybackButton = uibutton(app.ControlPanel, 'push');
            app.PlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @PlaybackButtonPushed, true);
            app.PlaybackButton.Text = 'Playback';
            app.VolumeSliderLabel = uilabel(app.ControlPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.ControlPanel);
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.DisplayDropDownLabel = uilabel(app.VisualizationPanel);
            app.DisplayDropDownLabel.Text = 'Display ';
            app.DisplayDropDown = uidropdown(app.VisualizationPanel);
            app.DisplayDropDown.Items = {'Waveform', 'Spectogram', 'Frequency Spectrum'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'Waveform';
            app.PrivacyLabel = uilabel(app.UIFigure);
            app.PrivacyLabel.Text = 'Privacy';
            app.HelpCenterLabel = uilabel(app.UIFigure);
            app.HelpCenterLabel.Text = 'Help Center';
            app.TermsLabel = uilabel(app.UIFigure);
            app.TermsLabel.Text = 'Terms';
            app.HowitworksLabel = uilabel(app.UIFigure);
            app.HowitworksLabel.Text = 'How it works';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results: ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.ConfidenceScoreLabel = uilabel(app.UIFigure);
            app.ConfidenceScoreLabel.Text = 'Confidence Score:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end